classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        Panel_2           matlab.ui.container.Panel
        rEditField        matlab.ui.control.NumericEditField
        rEditFieldLabel   matlab.ui.control.Label
        kEditField        matlab.ui.control.NumericEditField
        kEditFieldLabel   matlab.ui.control.Label
        dEditField        matlab.ui.control.NumericEditField
        dEditFieldLabel   matlab.ui.control.Label
        cEditField        matlab.ui.control.NumericEditField
        cEditFieldLabel   matlab.ui.control.Label
        bEditField        matlab.ui.control.NumericEditField
        bEditFieldLabel   matlab.ui.control.Label
        aEditField        matlab.ui.control.NumericEditField
        aEditFieldLabel   matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        EditField_4       matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        OKButton          matlab.ui.control.Button
        CheckBox_3        matlab.ui.control.CheckBox
        CheckBox_2        matlab.ui.control.CheckBox
        CheckBox          matlab.ui.control.CheckBox
        Panel_3           matlab.ui.container.Panel
        tEditField        matlab.ui.control.NumericEditField
        tEditFieldLabel   matlab.ui.control.Label
        yEditField        matlab.ui.control.NumericEditField
        yEditFieldLabel   matlab.ui.control.Label
        xEditField        matlab.ui.control.NumericEditField
        xEditFieldLabel   matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        Tab_2             matlab.ui.container.Tab
        CheckBox_4        matlab.ui.control.CheckBox
        RightPanel        matlab.ui.container.Panel
        EditFieldLabel    matlab.ui.control.Label
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            t1 = app.tEditField.Value;
            x1 = app.xEditField.Value;
            y1 = app.yEditField.Value;
            global alpha beta gamma delta;
            global a b c d k r;
            
            dropDownValue =  app.DropDown.Value;
            if strcmp(dropDownValue, '№1 Уравнение')
                alpha = app.EditField.Value;
                beta = app.EditField_2.Value;
                gamma = app.EditField_3.Value;
                delta = app.EditField_4.Value;
                [t,y]=ode45('lotkaFunc', [0 t1], [x1;y1]);
                
                xBalancePoint = delta/gamma;
                yBalancePoint = alpha/beta;
                app.Label.Text = "("+xBalancePoint+"; "+yBalancePoint+")";
            elseif strcmp(dropDownValue, '№2 Уравнение')
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                c = app.cEditField.Value;
                d = app.dEditField.Value;
                k = app.kEditField.Value;
                r = app.rEditField.Value;
                [t,y]=ode45('lotkaFunc1', [0 t1], [x1;y1]);
            end
            
            %График
            plot(app.UIAxes, t, y(:, 1), 'b', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, t, y(:, 2), 'r', 'LineWidth', 1.5);
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Время');
            ylabel(app.UIAxes, 'Количество особей в популяции');
            legend(app.UIAxes, 'Жертвы', 'Хищники');
            title(app.UIAxes,'График');
            %В отдельном окне
            if app.CheckBox_3.Value == 1
                figure;
                plot(t, y(:, 1), 'b', 'LineWidth', 1.5);
                hold on;
                plot(t, y(:, 2), 'r', 'LineWidth', 1.5);
                xlabel('Время');
                ylabel('Количество особей в популяции');
                legend('Жертвы', 'Хищники');
                title('График');
                hold off;
            end
            
            %Фазовый портрет
            plot(app.UIAxes2,y(:,1),y(:,2), 'k', 'LineWidth', 1.1);
            hold(app.UIAxes2, "on");            
            if app.CheckBox.Value == 1
               quiver(app.UIAxes2,y(:,1),y(:,2),gradient(y(:,1)),gradient(y(:,2)),'b','LineWidth',1.1); 
            end            
            hold(app.UIAxes2, "off");
            xlabel(app.UIAxes2,'Жертвы');
            ylabel(app.UIAxes2,'Хищники');
            title(app.UIAxes2,'Фазовая траектория');
            %В отдельном окне
            if app.CheckBox_2.Value == 1
                figure;
                plot(y(:,1),y(:,2), 'k', 'LineWidth', 1.1);
                hold on;
                if app.CheckBox.Value == 1
                    quiver(y(:,1),y(:,2),gradient(y(:,1)),gradient(y(:,2)),'b','LineWidth',1.1); 
                end
                xlabel('Жертвы');
                ylabel('Хищники');
                title('Фазовая траектория');
                hold off;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
            
            if strcmp(value, '№1 Уравнение')
                app.Panel.Visible = 'on';
            elseif strcmp(value, '№2 Уравнение')
                app.Panel_2.Visible = 'on';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {170, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'№1 Уравнение', '№2 Уравнение'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '№1 Уравнение';
            app.Panel_3 = uipanel(app.Tab);
            app.xEditFieldLabel = uilabel(app.Panel_3);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.Panel_3, 'numeric');
            app.yEditFieldLabel = uilabel(app.Panel_3);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Panel_3, 'numeric');
            app.tEditFieldLabel = uilabel(app.Panel_3);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.Panel_3, 'numeric');
            app.CheckBox = uicheckbox(app.Tab);
            app.CheckBox.Text = 'Направление фазовых траекторий';
            app.CheckBox_2 = uicheckbox(app.Tab);
            app.CheckBox_2.Text = 'ФП в отдельном окне';
            app.CheckBox_3 = uicheckbox(app.Tab);
            app.CheckBox_3.Text = 'График в отдельном окне';
            app.OKButton = uibutton(app.Tab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Параметры:';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'α';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = 'β';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_3Label = uilabel(app.Panel);
            app.EditField_3Label.Text = 'γ';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_4Label = uilabel(app.Panel);
            app.EditField_4Label.Text = 'δ';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.Tab_2 = uitab(app.TabGroup);
            app.CheckBox_4 = uicheckbox(app.Tab_2);
            app.CheckBox_4.Text = 'Фазовый портрет';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = 'Параметры:';
            app.Panel_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.Panel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.kEditFieldLabel = uilabel(app.Panel_2);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.Panel_2, 'numeric');
            app.rEditFieldLabel = uilabel(app.Panel_2);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel_2, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end